classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PREMUIFigure                    matlab.ui.Figure
        Label_3                         matlab.ui.control.Label
        OutputResultButton_2            matlab.ui.control.Button
        FAfterInspectionButton_2        matlab.ui.control.Button
        EInspectionStageButton_2        matlab.ui.control.Button
        DPendingInspectionButton_2      matlab.ui.control.Button
        CBowelPreparationButton_2       matlab.ui.control.Button
        BBeforeHospitalButton_2         matlab.ui.control.Button
        ABasicInformationButton_2       matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ATab                            matlab.ui.container.Tab
        GastrointestinalendoscopyexaminationsiteButtonGroup  matlab.ui.container.ButtonGroup
        ColonoscopyButton               matlab.ui.control.ToggleButton
        GastroscopyButton               matlab.ui.control.ToggleButton
        ButtonGroup_7                   matlab.ui.container.ButtonGroup
        OtherButton_2                   matlab.ui.control.RadioButton
        DivorceButton                   matlab.ui.control.RadioButton
        MarriedButton                   matlab.ui.control.RadioButton
        UnmarriedButton                 matlab.ui.control.RadioButton
        GenderLabel_7                   matlab.ui.control.Label
        ButtonGroup_6                   matlab.ui.container.ButtonGroup
        HospitalizationButton           matlab.ui.control.RadioButton
        OutpatientDepartmentButton      matlab.ui.control.RadioButton
        GenderLabel_6                   matlab.ui.control.Label
        ButtonGroup_5                   matlab.ui.container.ButtonGroup
        CityButton                      matlab.ui.control.RadioButton
        TownshipButton                  matlab.ui.control.RadioButton
        RuralareasButton                matlab.ui.control.RadioButton
        GenderLabel_5                   matlab.ui.control.Label
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        BusinessservicesButton          matlab.ui.control.RadioButton
        ProfessionalskillsButton        matlab.ui.control.RadioButton
        OtherButton                     matlab.ui.control.RadioButton
        AdministrativemanagementButton  matlab.ui.control.RadioButton
        WorkerButton                    matlab.ui.control.RadioButton
        FarmerButton                    matlab.ui.control.RadioButton
        GenderLabel_4                   matlab.ui.control.Label
        weightkgEditField               matlab.ui.control.NumericEditField
        weightkgEditFieldLabel          matlab.ui.control.Label
        A4Label                         matlab.ui.control.Label
        HeightcmEditField               matlab.ui.control.NumericEditField
        HeightcmEditFieldLabel          matlab.ui.control.Label
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        UniversityandaboveButton        matlab.ui.control.RadioButton
        HighschoolorvocationalschoolButton  matlab.ui.control.RadioButton
        JuniorhighschoolandbelowButton  matlab.ui.control.RadioButton
        GenderLabel_3                   matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        EthnicMinorityButton            matlab.ui.control.RadioButton
        HannationalityButton            matlab.ui.control.RadioButton
        GenderLabel_2                   matlab.ui.control.Label
        GenderLabel                     matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        FemaleButton                    matlab.ui.control.RadioButton
        MaleButton                      matlab.ui.control.RadioButton
        A1DateofBirthDatePicker         matlab.ui.control.DatePicker
        DateofBirthLabel                matlab.ui.control.Label
        BTab                            matlab.ui.container.Tab
        B1IwashappywiththewayIwasreferredforthetestLabel_8  matlab.ui.control.Label
        B1IwashappywiththewayIwasreferredforthetestLabel_7  matlab.ui.control.Label
        B1IwashappywiththewayIwasreferredforthetestLabel_6  matlab.ui.control.Label
        B1IwashappywiththewayIwasreferredforthetestLabel_5  matlab.ui.control.Label
        ButtonGroup_15                  matlab.ui.container.ButtonGroup
        Button_40                       matlab.ui.control.RadioButton
        Button_39                       matlab.ui.control.RadioButton
        Button_38                       matlab.ui.control.RadioButton
        Button_37                       matlab.ui.control.RadioButton
        Button_36                       matlab.ui.control.RadioButton
        ButtonGroup_14                  matlab.ui.container.ButtonGroup
        Button_35                       matlab.ui.control.RadioButton
        Button_34                       matlab.ui.control.RadioButton
        Button_33                       matlab.ui.control.RadioButton
        Button_32                       matlab.ui.control.RadioButton
        Button_31                       matlab.ui.control.RadioButton
        ButtonGroup_13                  matlab.ui.container.ButtonGroup
        Button_30                       matlab.ui.control.RadioButton
        Button_29                       matlab.ui.control.RadioButton
        Button_28                       matlab.ui.control.RadioButton
        Button_27                       matlab.ui.control.RadioButton
        Button_26                       matlab.ui.control.RadioButton
        ButtonGroup_12                  matlab.ui.container.ButtonGroup
        Button_25                       matlab.ui.control.RadioButton
        Button_24                       matlab.ui.control.RadioButton
        Button_23                       matlab.ui.control.RadioButton
        Button_22                       matlab.ui.control.RadioButton
        Button_21                       matlab.ui.control.RadioButton
        ButtonGroup_11                  matlab.ui.container.ButtonGroup
        Button_20                       matlab.ui.control.RadioButton
        Button_19                       matlab.ui.control.RadioButton
        Button_18                       matlab.ui.control.RadioButton
        Button_17                       matlab.ui.control.RadioButton
        Button_16                       matlab.ui.control.RadioButton
        ButtonGroup_10                  matlab.ui.container.ButtonGroup
        Button_15                       matlab.ui.control.RadioButton
        Button_14                       matlab.ui.control.RadioButton
        Button_13                       matlab.ui.control.RadioButton
        Button_12                       matlab.ui.control.RadioButton
        Button_11                       matlab.ui.control.RadioButton
        ButtonGroup_9                   matlab.ui.container.ButtonGroup
        Button_10                       matlab.ui.control.RadioButton
        Button_9                        matlab.ui.control.RadioButton
        Button_8                        matlab.ui.control.RadioButton
        Button_7                        matlab.ui.control.RadioButton
        Button_6                        matlab.ui.control.RadioButton
        B1IwashappywiththewayIwasreferredforthetestLabel_4  matlab.ui.control.Label
        B1IwashappywiththewayIwasreferredforthetestLabel_3  matlab.ui.control.Label
        B1IwashappywiththewayIwasreferredforthetestLabel_2  matlab.ui.control.Label
        B1IwashappywiththewayIwasreferredforthetestLabel  matlab.ui.control.Label
        StronglydisagreeLabel           matlab.ui.control.Label
        DisagreeLabel                   matlab.ui.control.Label
        NeitheragreeordisagreeLabel     matlab.ui.control.Label
        AgreeLabel                      matlab.ui.control.Label
        StronglyagreeLabel              matlab.ui.control.Label
        ButtonGroup_8                   matlab.ui.container.ButtonGroup
        Button_5                        matlab.ui.control.RadioButton
        Button_4                        matlab.ui.control.RadioButton
        Button_3                        matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button                          matlab.ui.control.RadioButton
        CTab                            matlab.ui.container.Tab
        C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_7  matlab.ui.control.Label
        C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_6  matlab.ui.control.Label
        C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_5  matlab.ui.control.Label
        C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_4  matlab.ui.control.Label
        C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_3  matlab.ui.control.Label
        C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_2  matlab.ui.control.Label
        C1FastingbeforetheexaminationmademeveryuncomfortableLabel  matlab.ui.control.Label
        ButtonGroup_22                  matlab.ui.container.ButtonGroup
        Button_75                       matlab.ui.control.RadioButton
        Button_74                       matlab.ui.control.RadioButton
        Button_73                       matlab.ui.control.RadioButton
        Button_72                       matlab.ui.control.RadioButton
        Button_71                       matlab.ui.control.RadioButton
        ButtonGroup_21                  matlab.ui.container.ButtonGroup
        Button_70                       matlab.ui.control.RadioButton
        Button_69                       matlab.ui.control.RadioButton
        Button_68                       matlab.ui.control.RadioButton
        Button_67                       matlab.ui.control.RadioButton
        Button_66                       matlab.ui.control.RadioButton
        ButtonGroup_20                  matlab.ui.container.ButtonGroup
        Button_65                       matlab.ui.control.RadioButton
        Button_64                       matlab.ui.control.RadioButton
        Button_63                       matlab.ui.control.RadioButton
        Button_62                       matlab.ui.control.RadioButton
        Button_61                       matlab.ui.control.RadioButton
        ButtonGroup_19                  matlab.ui.container.ButtonGroup
        Button_60                       matlab.ui.control.RadioButton
        Button_59                       matlab.ui.control.RadioButton
        Button_58                       matlab.ui.control.RadioButton
        Button_57                       matlab.ui.control.RadioButton
        Button_56                       matlab.ui.control.RadioButton
        ButtonGroup_18                  matlab.ui.container.ButtonGroup
        Button_55                       matlab.ui.control.RadioButton
        Button_54                       matlab.ui.control.RadioButton
        Button_53                       matlab.ui.control.RadioButton
        Button_52                       matlab.ui.control.RadioButton
        Button_51                       matlab.ui.control.RadioButton
        ButtonGroup_17                  matlab.ui.container.ButtonGroup
        Button_50                       matlab.ui.control.RadioButton
        Button_49                       matlab.ui.control.RadioButton
        Button_48                       matlab.ui.control.RadioButton
        Button_47                       matlab.ui.control.RadioButton
        Button_46                       matlab.ui.control.RadioButton
        StronglydisagreeLabel_2         matlab.ui.control.Label
        DisagreeLabel_2                 matlab.ui.control.Label
        NeitheragreeordisagreeLabel_2   matlab.ui.control.Label
        AgreeLabel_2                    matlab.ui.control.Label
        StronglyagreeLabel_2            matlab.ui.control.Label
        ButtonGroup_16                  matlab.ui.container.ButtonGroup
        Button_45                       matlab.ui.control.RadioButton
        Button_44                       matlab.ui.control.RadioButton
        Button_43                       matlab.ui.control.RadioButton
        Button_42                       matlab.ui.control.RadioButton
        Button_41                       matlab.ui.control.RadioButton
        DTab                            matlab.ui.container.Tab
        D4IhadnounansweredquestionsbeforethetestLabel  matlab.ui.control.Label
        StronglydisagreeLabel_3         matlab.ui.control.Label
        DisagreeLabel_3                 matlab.ui.control.Label
        NeitheragreeordisagreeLabel_3   matlab.ui.control.Label
        AgreeLabel_3                    matlab.ui.control.Label
        StronglyagreeLabel_3            matlab.ui.control.Label
        ButtonGroup_23                  matlab.ui.container.ButtonGroup
        Button_80                       matlab.ui.control.RadioButton
        Button_79                       matlab.ui.control.RadioButton
        Button_78                       matlab.ui.control.RadioButton
        Button_77                       matlab.ui.control.RadioButton
        Button_76                       matlab.ui.control.RadioButton
        ETab                            matlab.ui.container.Tab
        E23IfeltembarrassedbythepainIexperiencedLabel  matlab.ui.control.Label
        E22IfeltembarrassedbythediscomfortIexperiencedLabel  matlab.ui.control.Label
        E13ThetesttooklongerthanIexpectedLabel  matlab.ui.control.Label
        E9IwassatisfiedwiththeexplanationgiventomeaboutthetestLabel  matlab.ui.control.Label
        ButtonGroup_27                  matlab.ui.container.ButtonGroup
        Button_100                      matlab.ui.control.RadioButton
        Button_99                       matlab.ui.control.RadioButton
        Button_98                       matlab.ui.control.RadioButton
        Button_97                       matlab.ui.control.RadioButton
        Button_96                       matlab.ui.control.RadioButton
        ButtonGroup_26                  matlab.ui.container.ButtonGroup
        Button_95                       matlab.ui.control.RadioButton
        Button_94                       matlab.ui.control.RadioButton
        Button_93                       matlab.ui.control.RadioButton
        Button_92                       matlab.ui.control.RadioButton
        Button_91                       matlab.ui.control.RadioButton
        ButtonGroup_25                  matlab.ui.container.ButtonGroup
        Button_90                       matlab.ui.control.RadioButton
        Button_89                       matlab.ui.control.RadioButton
        Button_88                       matlab.ui.control.RadioButton
        Button_87                       matlab.ui.control.RadioButton
        Button_86                       matlab.ui.control.RadioButton
        StronglydisagreeLabel_4         matlab.ui.control.Label
        DisagreeLabel_4                 matlab.ui.control.Label
        NeitheragreeordisagreeLabel_4   matlab.ui.control.Label
        AgreeLabel_4                    matlab.ui.control.Label
        StronglyagreeLabel_4            matlab.ui.control.Label
        ButtonGroup_24                  matlab.ui.container.ButtonGroup
        Button_85                       matlab.ui.control.RadioButton
        Button_84                       matlab.ui.control.RadioButton
        Button_83                       matlab.ui.control.RadioButton
        Button_82                       matlab.ui.control.RadioButton
        Button_81                       matlab.ui.control.RadioButton
        FTab                            matlab.ui.container.Tab
        F6IhaddiscomfortafterthetestLabel_3  matlab.ui.control.Label
        F6IhaddiscomfortafterthetestLabel_2  matlab.ui.control.Label
        F6IhaddiscomfortafterthetestLabel  matlab.ui.control.Label
        F2IhaddiscomfortafterthetestLabel  matlab.ui.control.Label
        ButtonGroup_32                  matlab.ui.container.ButtonGroup
        Button_125                      matlab.ui.control.RadioButton
        Button_124                      matlab.ui.control.RadioButton
        Button_123                      matlab.ui.control.RadioButton
        Button_122                      matlab.ui.control.RadioButton
        Button_121                      matlab.ui.control.RadioButton
        ButtonGroup_31                  matlab.ui.container.ButtonGroup
        Button_120                      matlab.ui.control.RadioButton
        Button_119                      matlab.ui.control.RadioButton
        Button_118                      matlab.ui.control.RadioButton
        Button_117                      matlab.ui.control.RadioButton
        Button_116                      matlab.ui.control.RadioButton
        ButtonGroup_30                  matlab.ui.container.ButtonGroup
        Button_115                      matlab.ui.control.RadioButton
        Button_114                      matlab.ui.control.RadioButton
        Button_113                      matlab.ui.control.RadioButton
        Button_112                      matlab.ui.control.RadioButton
        Button_111                      matlab.ui.control.RadioButton
        StronglydisagreeLabel_5         matlab.ui.control.Label
        DisagreeLabel_5                 matlab.ui.control.Label
        NeitheragreeordisagreeLabel_5   matlab.ui.control.Label
        AgreeLabel_5                    matlab.ui.control.Label
        StronglyagreeLabel_5            matlab.ui.control.Label
        ButtonGroup_29                  matlab.ui.container.ButtonGroup
        Button_110                      matlab.ui.control.RadioButton
        Button_109                      matlab.ui.control.RadioButton
        Button_108                      matlab.ui.control.RadioButton
        Button_107                      matlab.ui.control.RadioButton
        Button_106                      matlab.ui.control.RadioButton
        Tab                             matlab.ui.container.Tab
        GetHelpButton                   matlab.ui.control.Button
        SaveResultsButton               matlab.ui.control.Button
        CleanupButton                   matlab.ui.control.Button
        StartOutputButton               matlab.ui.control.Button
        PREMresultevaluationLabel       matlab.ui.control.Label
        ScoreforeachdimensionLabel      matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        cellArrayText % Description
    end

    methods (Access = private)

        function outPut(app,str)
            string = sprintf('%s\n', str); % 将数据格式化为字符串或字符向量
            app.cellArrayText=horzcat(app.cellArrayText,string); % 水平串联数组
        end

        function B1 = sele2num(app,selectedButton)
            switch selectedButton
                case '5'
                    B1=5;
                case '4'
                    B1=4;
                case '3'
                    B1=3;
                case '2'
                    B1=2;
                case '1'
                    B1=1;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ABasicInformationButton_2
        function ABasicInformationButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.ATab;
        end

        % Button pushed function: BBeforeHospitalButton_2
        function BBeforeHospitalButton_2Pushed(app, event)
            % 切换至B类
            app.TabGroup.SelectedTab=app.BTab;
        end

        % Button pushed function: CBowelPreparationButton_2
        function CBowelPreparationButton_2Pushed(app, event)
            % 切换至C类
            app.TabGroup.SelectedTab=app.CTab;
        end

        % Button pushed function: DPendingInspectionButton_2
        function DPendingInspectionButton_2Pushed(app, event)
            % 切换至D类
            app.TabGroup.SelectedTab=app.DTab;
        end

        % Button pushed function: EInspectionStageButton_2
        function EInspectionStageButton_2Pushed(app, event)
            % 切换至E类
            app.TabGroup.SelectedTab=app.ETab;
        end

        % Button pushed function: FAfterInspectionButton_2
        function FAfterInspectionButton_2Pushed(app, event)
            % 切换至F类
            app.TabGroup.SelectedTab=app.FTab;
        end

        % Button pushed function: OutputResultButton_2
        function OutputResultButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.Tab;
        end

        % Button pushed function: StartOutputButton
        function StartOutputButtonPushed(app, event)
            %% 判断是肠镜还是胃镜
            selectedButton= app.GastrointestinalendoscopyexaminationsiteButtonGroup.SelectedObject;
            SCL=selectedButton.Text;
            if SCL == 'Colonoscopy'
            
            %% B类的值
            % 获取B1的值
            selectedButton1=app.ButtonGroup_8.SelectedObject;
            B1 = sele2num(app,selectedButton1.Text);
            % 获取B2的值
            selectedButton2=app.ButtonGroup_9.SelectedObject;
            B2 = sele2num(app,selectedButton2.Text);
            % 获取B6的值
            selectedButton3=app.ButtonGroup_10.SelectedObject;
            B6 = sele2num(app,selectedButton3.Text);
            % 获取B7的值
            selectedButton4=app.ButtonGroup_11.SelectedObject;
            B7 = sele2num(app,selectedButton4.Text);
            % 获取B10的值
            selectedButton5=app.ButtonGroup_12.SelectedObject;
            B10 = sele2num(app,selectedButton5.Text);
            % 获取B11的值
            selectedButton6=app.ButtonGroup_13.SelectedObject;
            B11 = sele2num(app,selectedButton6.Text);
            % 获取B12的值
            selectedButton7=app.ButtonGroup_14.SelectedObject;
            B12 = sele2num(app,selectedButton7.Text);
            % 获取B14的值
            selectedButton8=app.ButtonGroup_15.SelectedObject;
            B14 = sele2num(app,selectedButton8.Text);
            %% C类的值
            % 获取C1的值
            selectedButton1=app.ButtonGroup_16.SelectedObject;
            C1 = sele2num(app,selectedButton1.Text);
            % 获取C2的值
            selectedButton2=app.ButtonGroup_17.SelectedObject;
            C2 = sele2num(app,selectedButton2.Text);
            % 获取C5的值
            selectedButton3=app.ButtonGroup_18.SelectedObject;
            C5 = sele2num(app,selectedButton3.Text);
            % 获取C6的值
            selectedButton4=app.ButtonGroup_19.SelectedObject;
            C6 = sele2num(app,selectedButton4.Text);
            % 获取C7的值
            selectedButton5=app.ButtonGroup_20.SelectedObject;
            C7 = sele2num(app,selectedButton5.Text);
            % 获取C8的值
            selectedButton6=app.ButtonGroup_21.SelectedObject;
            C8 = sele2num(app,selectedButton6.Text);
            % 获取C9的值
            selectedButton7=app.ButtonGroup_22.SelectedObject;
            C9 = sele2num(app,selectedButton7.Text);
            %% D类的值
            % 获取D4的值
            selectedButton1=app.ButtonGroup_23.SelectedObject;
            D4 = sele2num(app,selectedButton1.Text);
            %% E类的值
            % 获取E9的值
            selectedButton1=app.ButtonGroup_24.SelectedObject;
            E9 = sele2num(app,selectedButton1.Text);
            % 获取E13的值
            selectedButton2=app.ButtonGroup_25.SelectedObject;
            E13 = sele2num(app,selectedButton2.Text);
            % 获取E22的值
            selectedButton3=app.ButtonGroup_26.SelectedObject;
            E22 = sele2num(app,selectedButton3.Text);
            % 获取E23的值
            selectedButton4=app.ButtonGroup_27.SelectedObject;
            E23 = sele2num(app,selectedButton4.Text);
            %% F类的值
            % 获取F2的值
            selectedButton1=app.ButtonGroup_29.SelectedObject;
            F2 = sele2num(app,selectedButton1.Text);
            % 获取F6的值
            selectedButton2=app.ButtonGroup_30.SelectedObject;
            F6 = sele2num(app,selectedButton2.Text);
            % 获取F9的值
            selectedButton3=app.ButtonGroup_31.SelectedObject;
            F9 = sele2num(app,selectedButton3.Text);
            % 获取F10的值
            selectedButton4=app.ButtonGroup_32.SelectedObject;
            F10 = sele2num(app,selectedButton4.Text);
            data1=[B1 B2 B6 B7];
            data2=[B10 B11 B12 B14];
            data3=[C1 C2 C5 C7];
            data4=[C6 C8 C9];
            data5=[D4 E9];
            data6=[E13 E22 E23 F2];
            data7=[F6 F9 F10];
            load MD.mat
            pre1=predict(MD1,data1);
            pre2=predict(MD2,data2);
            pre3=predict(MD3,data3);
            pre4=predict(MD4,data4);
            pre5=predict(MD5,data5);
            pre6=predict(MD6,data6);
            pre7=predict(MD7,data7);
            app.cellArrayText{1} = sprintf('%s\n', '***Output of measurement results for each dimension***'); % 赋初值
            outPut(app,['Dimension 1=' num2str(pre1)])
            outPut(app,['Dimension 2=' num2str(pre2)])
            outPut(app,['Dimension 3=' num2str(pre3)])
            outPut(app,['Dimension 4=' num2str(pre4)])
            outPut(app,['Dimension 5=' num2str(pre5)])
            outPut(app,['Dimension 6=' num2str(pre6)])
            outPut(app,['Dimension 7=' num2str(pre7)])
            outPut(app,'***Cluster evaluation result output***')
            
            %% 计算分类
            PRE=[pre1 pre2 pre3 pre4 pre5 pre6 pre7];
            distans=pdist2(PRE,centroids);
            [~, inx] = min(distans, [], 2);
            if inx==1
                outPut(app,'This patient is a "Positive Experiencer," satisfied with the examination process and results.')


            elseif inx==2
                outPut(app,'This patient is a "Negative Experiencer," with no strong positive or negative feelings.')
            elseif inx==3
                outPut(app,'This patient is a "Indifferent Individuals," dissatisfied with the examination process and results.')
            end
            app.TextArea.Value=app.cellArrayText;
            app.cellArrayText=[];
            bar(app.UIAxes,PRE);
            app.UIAxes.XTickLabel={'D1', 'D2', 'D3','D4','D5', 'D6', 'D7'}; % 设置x轴刻度标签
            else
            %% B类的值
            % 获取B1的值
            selectedButton1=app.ButtonGroup_8.SelectedObject;
            B1 = sele2num(app,selectedButton1.Text);
            % 获取B2的值
            selectedButton2=app.ButtonGroup_9.SelectedObject;
            B2 = sele2num(app,selectedButton2.Text);
            % 获取B6的值
            selectedButton3=app.ButtonGroup_10.SelectedObject;
            B6 = sele2num(app,selectedButton3.Text);
            % 获取B7的值
            selectedButton4=app.ButtonGroup_11.SelectedObject;
            B7 = sele2num(app,selectedButton4.Text);
            % 获取B10的值
            selectedButton5=app.ButtonGroup_12.SelectedObject;
            B10 = sele2num(app,selectedButton5.Text);
            % 获取B11的值
            selectedButton6=app.ButtonGroup_13.SelectedObject;
            B11 = sele2num(app,selectedButton6.Text);
            % 获取B12的值
            selectedButton7=app.ButtonGroup_14.SelectedObject;
            B12 = sele2num(app,selectedButton7.Text);
            % 获取B14的值
            selectedButton8=app.ButtonGroup_15.SelectedObject;
            B14 = sele2num(app,selectedButton8.Text);
            % %% C类的值
            % % 获取C1的值
            % selectedButton1=app.ButtonGroup_16.SelectedObject;
            % C1 = sele2num(app,selectedButton1.Text);
            % % 获取C2的值
            % selectedButton2=app.ButtonGroup_17.SelectedObject;
            % C2 = sele2num(app,selectedButton2.Text);
            % % 获取C5的值
            % selectedButton3=app.ButtonGroup_18.SelectedObject;
            % C5 = sele2num(app,selectedButton3.Text);
            % % 获取C6的值
            % selectedButton4=app.ButtonGroup_19.SelectedObject;
            % C6 = sele2num(app,selectedButton4.Text);
            % % 获取C7的值
            % selectedButton5=app.ButtonGroup_20.SelectedObject;
            % C7 = sele2num(app,selectedButton5.Text);
            % % 获取C8的值
            % selectedButton6=app.ButtonGroup_21.SelectedObject;
            % C8 = sele2num(app,selectedButton6.Text);
            % % 获取C9的值
            % selectedButton7=app.ButtonGroup_22.SelectedObject;
            % C9 = sele2num(app,selectedButton7.Text);
            %% D类的值
            % 获取D4的值
            selectedButton1=app.ButtonGroup_23.SelectedObject;
            D4 = sele2num(app,selectedButton1.Text);
            %% E类的值
            % 获取E9的值
            selectedButton1=app.ButtonGroup_24.SelectedObject;
            E9 = sele2num(app,selectedButton1.Text);
            % 获取E13的值
            selectedButton2=app.ButtonGroup_25.SelectedObject;
            E13 = sele2num(app,selectedButton2.Text);
            % 获取E22的值
            selectedButton3=app.ButtonGroup_26.SelectedObject;
            E22 = sele2num(app,selectedButton3.Text);
            % 获取E23的值
            selectedButton4=app.ButtonGroup_27.SelectedObject;
            E23 = sele2num(app,selectedButton4.Text);
            %% F类的值
            % 获取F2的值
            selectedButton1=app.ButtonGroup_29.SelectedObject;
            F2 = sele2num(app,selectedButton1.Text);
            % 获取F6的值
            selectedButton2=app.ButtonGroup_30.SelectedObject;
            F6 = sele2num(app,selectedButton2.Text);
            % 获取F9的值
            selectedButton3=app.ButtonGroup_31.SelectedObject;
            F9 = sele2num(app,selectedButton3.Text);
            % 获取F10的值
            selectedButton4=app.ButtonGroup_32.SelectedObject;
            F10 = sele2num(app,selectedButton4.Text);
            data1=[B1 B2 B6 B7];
            data2=[B10 B11 B12 B14];
            % data3=[C1 C2 C5 C7];
            % data4=[C6 C8 C9];
            data5=[D4 E9];
            data6=[E13 E22 E23 F2];
            data7=[F6 F9 F10];
            load MD.mat
            pre1=predict(MD1,data1);
            pre2=predict(MD2,data2);
            % pre3=predict(MD3,data3);
            % pre4=predict(MD4,data4);
            pre5=predict(MD5,data5);
            pre6=predict(MD6,data6);
            pre7=predict(MD7,data7);
            app.cellArrayText{1} = sprintf('%s\n', '***Output of measurement results for each dimension***'); % 赋初值
            outPut(app,['Dimension 1=' num2str(pre1)])
            outPut(app,['Dimension 2=' num2str(pre2)])
            % outPut(app,['Dimension 3=' num2str(pre3)])
            % outPut(app,['Dimension 4=' num2str(pre4)])
            outPut(app,['Dimension 5=' num2str(pre5)])
            outPut(app,['Dimension 6=' num2str(pre6)])
            outPut(app,['Dimension 7=' num2str(pre7)])
            outPut(app,'***Cluster evaluation result output***')
            
            %% 计算分类
            PRE=[pre1 pre2 pre5 pre6 pre7];
            centroids(:,3:4)=[];
            distans=pdist2(PRE,centroids);
            [~, inx] = min(distans, [], 2);
            if inx==1
                outPut(app,'This patient is a "Positive Experiencer," satisfied with the examination process and results.')


            elseif inx==2
                outPut(app,'This patient is a "Negative Experiencer," with no strong positive or negative feelings.')
            elseif inx==3
                outPut(app,'This patient is a "Indifferent Individuals," dissatisfied with the examination process and results.')
            end
            app.TextArea.Value=app.cellArrayText;
            app.cellArrayText=[];
            bar(app.UIAxes,PRE);
            % app.UIAxes.XTick([1 2 3 4 5]); % 设置x轴刻度
            app.UIAxes.XTickLabel={'D1', 'D2', 'D5', 'D6', 'D7'}; % 设置x轴刻度标签
            end 
            
        end

        % Button pushed function: CleanupButton
        function CleanupButtonPushed(app, event)
            app.cellArrayText{1} = sprintf('%s\n', '***Output of measurement results for each dimension***'); % 赋初值
            app.TextArea.Value=app.cellArrayText;
            app.cellArrayText=[];
            cla(app.UIAxes);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            % 获取文件路径和文件名
            [file, path] = uiputfile({'*.png';'*.jpg';'*.tif';'*.pdf'}, 'Save Image');
            if isequal(file, 0)
                return; % 用户取消保存
            end
            filename = fullfile(path, file);
            
            % 保存坐标区的图片
            exportgraphics(app.UIAxes, filename);
        end

        % Button pushed function: GetHelpButton
        function GetHelpButtonPushed(app, event)
            url='https://kdocs.cn/l/cfVLBAYCchGa';
            web(url)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PREMUIFigure = uifigure('Visible', 'off');
            app.PREMUIFigure.Name = 'PREM';
            app.PREMUIFigure.Icon = fullfile(pathToMLAPP, '徽.png');
            app.TabGroup = uitabgroup(app.PREMUIFigure);
            app.ATab = uitab(app.TabGroup);
            app.ATab.Title = 'A-基本';
            app.DateofBirthLabel = uilabel(app.ATab);
            app.DateofBirthLabel.Text = 'A1-Date of Birth:';
            app.A1DateofBirthDatePicker = uidatepicker(app.ATab);
            app.ButtonGroup = uibuttongroup(app.ATab);
            app.MaleButton = uiradiobutton(app.ButtonGroup);
            app.MaleButton.Text = 'Male';
            app.MaleButton.Value = true;
            app.FemaleButton = uiradiobutton(app.ButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.GenderLabel = uilabel(app.ATab);
            app.GenderLabel.Text = 'A2-Gender:';
            app.GenderLabel_2 = uilabel(app.ATab);
            app.GenderLabel_2.Text = 'A3-Race:';
            app.ButtonGroup_2 = uibuttongroup(app.ATab);
            app.HannationalityButton = uiradiobutton(app.ButtonGroup_2);
            app.HannationalityButton.Text = 'Han nationality';
            app.HannationalityButton.Value = true;
            app.EthnicMinorityButton = uiradiobutton(app.ButtonGroup_2);
            app.EthnicMinorityButton.Text = 'Ethnic Minority';
            app.GenderLabel_3 = uilabel(app.ATab);
            app.GenderLabel_3.Text = 'A5-Education:';
            app.ButtonGroup_3 = uibuttongroup(app.ATab);
            app.JuniorhighschoolandbelowButton = uiradiobutton(app.ButtonGroup_3);
            app.JuniorhighschoolandbelowButton.Text = 'Junior high school and below';
            app.JuniorhighschoolandbelowButton.Value = true;
            app.HighschoolorvocationalschoolButton = uiradiobutton(app.ButtonGroup_3);
            app.HighschoolorvocationalschoolButton.Text = 'High school or vocational school';
            app.UniversityandaboveButton = uiradiobutton(app.ButtonGroup_3);
            app.UniversityandaboveButton.Text = 'University and above';
            app.HeightcmEditFieldLabel = uilabel(app.ATab);
            app.HeightcmEditFieldLabel.Text = 'Height (cm)';
            app.HeightcmEditField = uieditfield(app.ATab, 'numeric');
            app.A4Label = uilabel(app.ATab);
            app.A4Label.Text = 'A4:';
            app.weightkgEditFieldLabel = uilabel(app.ATab);
            app.weightkgEditFieldLabel.Text = 'weight(kg)';
            app.weightkgEditField = uieditfield(app.ATab, 'numeric');
            app.GenderLabel_4 = uilabel(app.ATab);
            app.GenderLabel_4.Text = 'A6-Career:';
            app.ButtonGroup_4 = uibuttongroup(app.ATab);
            app.FarmerButton = uiradiobutton(app.ButtonGroup_4);
            app.FarmerButton.Text = 'Farmer';
            app.FarmerButton.Value = true;
            app.WorkerButton = uiradiobutton(app.ButtonGroup_4);
            app.WorkerButton.Text = 'Worker';
            app.AdministrativemanagementButton = uiradiobutton(app.ButtonGroup_4);
            app.AdministrativemanagementButton.Text = 'Administrative management';
            app.OtherButton = uiradiobutton(app.ButtonGroup_4);
            app.OtherButton.Text = 'Other';
            app.ProfessionalskillsButton = uiradiobutton(app.ButtonGroup_4);
            app.ProfessionalskillsButton.Text = 'Professional skills';
            app.BusinessservicesButton = uiradiobutton(app.ButtonGroup_4);
            app.BusinessservicesButton.Text = 'Business services';
            app.GenderLabel_5 = uilabel(app.ATab);
            app.GenderLabel_5.Text = 'A7-Living location:';
            app.ButtonGroup_5 = uibuttongroup(app.ATab);
            app.RuralareasButton = uiradiobutton(app.ButtonGroup_5);
            app.RuralareasButton.Text = 'Rural areas';
            app.RuralareasButton.Value = true;
            app.TownshipButton = uiradiobutton(app.ButtonGroup_5);
            app.TownshipButton.Text = 'Township';
            app.CityButton = uiradiobutton(app.ButtonGroup_5);
            app.CityButton.Text = 'City';
            app.GenderLabel_6 = uilabel(app.ATab);
            app.GenderLabel_6.Text = 'A15:';
            app.ButtonGroup_6 = uibuttongroup(app.ATab);
            app.OutpatientDepartmentButton = uiradiobutton(app.ButtonGroup_6);
            app.OutpatientDepartmentButton.Text = 'Outpatient Department';
            app.OutpatientDepartmentButton.Value = true;
            app.HospitalizationButton = uiradiobutton(app.ButtonGroup_6);
            app.HospitalizationButton.Text = 'Hospitalization';
            app.GenderLabel_7 = uilabel(app.ATab);
            app.GenderLabel_7.Text = 'A8-Marital status:';
            app.ButtonGroup_7 = uibuttongroup(app.ATab);
            app.UnmarriedButton = uiradiobutton(app.ButtonGroup_7);
            app.UnmarriedButton.Text = 'Unmarried';
            app.UnmarriedButton.Value = true;
            app.MarriedButton = uiradiobutton(app.ButtonGroup_7);
            app.MarriedButton.Text = 'Married';
            app.DivorceButton = uiradiobutton(app.ButtonGroup_7);
            app.DivorceButton.Text = 'Divorce';
            app.OtherButton_2 = uiradiobutton(app.ButtonGroup_7);
            app.OtherButton_2.Text = 'Other';
            app.GastrointestinalendoscopyexaminationsiteButtonGroup = uibuttongroup(app.ATab);
            app.GastrointestinalendoscopyexaminationsiteButtonGroup.Title = 'Gastrointestinal endoscopy examination site';
            app.GastroscopyButton = uitogglebutton(app.GastrointestinalendoscopyexaminationsiteButtonGroup);
            app.GastroscopyButton.Icon = fullfile(pathToMLAPP, '胃镜.png');
            app.GastroscopyButton.Text = 'Gastroscopy';
            app.GastroscopyButton.Value = true;
            app.ColonoscopyButton = uitogglebutton(app.GastrointestinalendoscopyexaminationsiteButtonGroup);
            app.ColonoscopyButton.Icon = fullfile(pathToMLAPP, '肠镜.png');
            app.ColonoscopyButton.Text = 'Colonoscopy';
            app.BTab = uitab(app.TabGroup);
            app.BTab.Title = 'B-到医院之前';
            app.ButtonGroup_8 = uibuttongroup(app.BTab);
            app.Button = uiradiobutton(app.ButtonGroup_8);
            app.Button.Text = '5';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup_8);
            app.Button_2.Text = '4';
            app.Button_3 = uiradiobutton(app.ButtonGroup_8);
            app.Button_3.Text = '3';
            app.Button_4 = uiradiobutton(app.ButtonGroup_8);
            app.Button_4.Text = '2';
            app.Button_5 = uiradiobutton(app.ButtonGroup_8);
            app.Button_5.Text = '1';
            app.StronglyagreeLabel = uilabel(app.BTab);
            app.StronglyagreeLabel.Text = 'Strongly agree';
            app.AgreeLabel = uilabel(app.BTab);
            app.AgreeLabel.Text = 'Agree';
            app.NeitheragreeordisagreeLabel = uilabel(app.BTab);
            app.NeitheragreeordisagreeLabel.Text = 'Neither agree or disagree';
            app.DisagreeLabel = uilabel(app.BTab);
            app.DisagreeLabel.Text = 'Disagree';
            app.StronglydisagreeLabel = uilabel(app.BTab);
            app.StronglydisagreeLabel.Text = 'Strongly disagree';
            app.B1IwashappywiththewayIwasreferredforthetestLabel = uilabel(app.BTab);
            app.B1IwashappywiththewayIwasreferredforthetestLabel.Text = {'B1: I was happy with the way I was '; ' referred for the test'};
            app.B1IwashappywiththewayIwasreferredforthetestLabel_2 = uilabel(app.BTab);
            app.B1IwashappywiththewayIwasreferredforthetestLabel_2.Text = {'B2: The time from first being referred to '; ' having the test done was satisfactory'};
            app.B1IwashappywiththewayIwasreferredforthetestLabel_3 = uilabel(app.BTab);
            app.B1IwashappywiththewayIwasreferredforthetestLabel_3.Text = {'B6: After reading the information, I did '; 'not have any questions about the test'};
            app.B1IwashappywiththewayIwasreferredforthetestLabel_4 = uilabel(app.BTab);
            app.B1IwashappywiththewayIwasreferredforthetestLabel_4.Text = {'B7: The instructions on what I needed to '; ' do before the test were easy to follow'};
            app.ButtonGroup_9 = uibuttongroup(app.BTab);
            app.Button_6 = uiradiobutton(app.ButtonGroup_9);
            app.Button_6.Text = '5';
            app.Button_6.Value = true;
            app.Button_7 = uiradiobutton(app.ButtonGroup_9);
            app.Button_7.Text = '4';
            app.Button_8 = uiradiobutton(app.ButtonGroup_9);
            app.Button_8.Text = '3';
            app.Button_9 = uiradiobutton(app.ButtonGroup_9);
            app.Button_9.Text = '2';
            app.Button_10 = uiradiobutton(app.ButtonGroup_9);
            app.Button_10.Text = '1';
            app.ButtonGroup_10 = uibuttongroup(app.BTab);
            app.Button_11 = uiradiobutton(app.ButtonGroup_10);
            app.Button_11.Text = '5';
            app.Button_11.Value = true;
            app.Button_12 = uiradiobutton(app.ButtonGroup_10);
            app.Button_12.Text = '4';
            app.Button_13 = uiradiobutton(app.ButtonGroup_10);
            app.Button_13.Text = '3';
            app.Button_14 = uiradiobutton(app.ButtonGroup_10);
            app.Button_14.Text = '2';
            app.Button_15 = uiradiobutton(app.ButtonGroup_10);
            app.Button_15.Text = '1';
            app.ButtonGroup_11 = uibuttongroup(app.BTab);
            app.Button_16 = uiradiobutton(app.ButtonGroup_11);
            app.Button_16.Text = '5';
            app.Button_16.Value = true;
            app.Button_17 = uiradiobutton(app.ButtonGroup_11);
            app.Button_17.Text = '4';
            app.Button_18 = uiradiobutton(app.ButtonGroup_11);
            app.Button_18.Text = '3';
            app.Button_19 = uiradiobutton(app.ButtonGroup_11);
            app.Button_19.Text = '2';
            app.Button_20 = uiradiobutton(app.ButtonGroup_11);
            app.Button_20.Text = '1';
            app.ButtonGroup_12 = uibuttongroup(app.BTab);
            app.Button_21 = uiradiobutton(app.ButtonGroup_12);
            app.Button_21.Text = '5';
            app.Button_21.Value = true;
            app.Button_22 = uiradiobutton(app.ButtonGroup_12);
            app.Button_22.Text = '4';
            app.Button_23 = uiradiobutton(app.ButtonGroup_12);
            app.Button_23.Text = '3';
            app.Button_24 = uiradiobutton(app.ButtonGroup_12);
            app.Button_24.Text = '2';
            app.Button_25 = uiradiobutton(app.ButtonGroup_12);
            app.Button_25.Text = '1';
            app.ButtonGroup_13 = uibuttongroup(app.BTab);
            app.Button_26 = uiradiobutton(app.ButtonGroup_13);
            app.Button_26.Text = '5';
            app.Button_26.Value = true;
            app.Button_27 = uiradiobutton(app.ButtonGroup_13);
            app.Button_27.Text = '4';
            app.Button_28 = uiradiobutton(app.ButtonGroup_13);
            app.Button_28.Text = '3';
            app.Button_29 = uiradiobutton(app.ButtonGroup_13);
            app.Button_29.Text = '2';
            app.Button_30 = uiradiobutton(app.ButtonGroup_13);
            app.Button_30.Text = '1';
            app.ButtonGroup_14 = uibuttongroup(app.BTab);
            app.Button_31 = uiradiobutton(app.ButtonGroup_14);
            app.Button_31.Text = '5';
            app.Button_31.Value = true;
            app.Button_32 = uiradiobutton(app.ButtonGroup_14);
            app.Button_32.Text = '4';
            app.Button_33 = uiradiobutton(app.ButtonGroup_14);
            app.Button_33.Text = '3';
            app.Button_34 = uiradiobutton(app.ButtonGroup_14);
            app.Button_34.Text = '2';
            app.Button_35 = uiradiobutton(app.ButtonGroup_14);
            app.Button_35.Text = '1';
            app.ButtonGroup_15 = uibuttongroup(app.BTab);
            app.Button_36 = uiradiobutton(app.ButtonGroup_15);
            app.Button_36.Text = '5';
            app.Button_36.Value = true;
            app.Button_37 = uiradiobutton(app.ButtonGroup_15);
            app.Button_37.Text = '4';
            app.Button_38 = uiradiobutton(app.ButtonGroup_15);
            app.Button_38.Text = '3';
            app.Button_39 = uiradiobutton(app.ButtonGroup_15);
            app.Button_39.Text = '2';
            app.Button_40 = uiradiobutton(app.ButtonGroup_15);
            app.Button_40.Text = '1';
            app.B1IwashappywiththewayIwasreferredforthetestLabel_5 = uilabel(app.BTab);
            app.B1IwashappywiththewayIwasreferredforthetestLabel_5.Text = {'B10: I was made anxious by talking to '; 'other people who had previously '; 'had the test'};
            app.B1IwashappywiththewayIwasreferredforthetestLabel_6 = uilabel(app.BTab);
            app.B1IwashappywiththewayIwasreferredforthetestLabel_6.Text = {'B11: I felt anxious about the results of'; ' the test'};
            app.B1IwashappywiththewayIwasreferredforthetestLabel_7 = uilabel(app.BTab);
            app.B1IwashappywiththewayIwasreferredforthetestLabel_7.Text = {'B12: I expected to experience discomfort '; ' during the test'};
            app.B1IwashappywiththewayIwasreferredforthetestLabel_8 = uilabel(app.BTab);
            app.B1IwashappywiththewayIwasreferredforthetestLabel_8.Text = {'B14: I was worried that inserting the tube/ '; ' camera would cause discomfort'};
            app.CTab = uitab(app.TabGroup);
            app.CTab.Title = 'C-肠道准备阶段';
            app.ButtonGroup_16 = uibuttongroup(app.CTab);
            app.Button_41 = uiradiobutton(app.ButtonGroup_16);
            app.Button_41.Text = '5';
            app.Button_41.Value = true;
            app.Button_42 = uiradiobutton(app.ButtonGroup_16);
            app.Button_42.Text = '4';
            app.Button_43 = uiradiobutton(app.ButtonGroup_16);
            app.Button_43.Text = '3';
            app.Button_44 = uiradiobutton(app.ButtonGroup_16);
            app.Button_44.Text = '2';
            app.Button_45 = uiradiobutton(app.ButtonGroup_16);
            app.Button_45.Text = '1';
            app.StronglyagreeLabel_2 = uilabel(app.CTab);
            app.StronglyagreeLabel_2.Text = 'Strongly agree';
            app.AgreeLabel_2 = uilabel(app.CTab);
            app.AgreeLabel_2.Text = 'Agree';
            app.NeitheragreeordisagreeLabel_2 = uilabel(app.CTab);
            app.NeitheragreeordisagreeLabel_2.Text = 'Neither agree or disagree';
            app.DisagreeLabel_2 = uilabel(app.CTab);
            app.DisagreeLabel_2.Text = 'Disagree';
            app.StronglydisagreeLabel_2 = uilabel(app.CTab);
            app.StronglydisagreeLabel_2.Text = 'Strongly disagree';
            app.ButtonGroup_17 = uibuttongroup(app.CTab);
            app.Button_46 = uiradiobutton(app.ButtonGroup_17);
            app.Button_46.Text = '5';
            app.Button_46.Value = true;
            app.Button_47 = uiradiobutton(app.ButtonGroup_17);
            app.Button_47.Text = '4';
            app.Button_48 = uiradiobutton(app.ButtonGroup_17);
            app.Button_48.Text = '3';
            app.Button_49 = uiradiobutton(app.ButtonGroup_17);
            app.Button_49.Text = '2';
            app.Button_50 = uiradiobutton(app.ButtonGroup_17);
            app.Button_50.Text = '1';
            app.ButtonGroup_18 = uibuttongroup(app.CTab);
            app.Button_51 = uiradiobutton(app.ButtonGroup_18);
            app.Button_51.Text = '5';
            app.Button_51.Value = true;
            app.Button_52 = uiradiobutton(app.ButtonGroup_18);
            app.Button_52.Text = '4';
            app.Button_53 = uiradiobutton(app.ButtonGroup_18);
            app.Button_53.Text = '3';
            app.Button_54 = uiradiobutton(app.ButtonGroup_18);
            app.Button_54.Text = '2';
            app.Button_55 = uiradiobutton(app.ButtonGroup_18);
            app.Button_55.Text = '1';
            app.ButtonGroup_19 = uibuttongroup(app.CTab);
            app.Button_56 = uiradiobutton(app.ButtonGroup_19);
            app.Button_56.Text = '5';
            app.Button_56.Value = true;
            app.Button_57 = uiradiobutton(app.ButtonGroup_19);
            app.Button_57.Text = '4';
            app.Button_58 = uiradiobutton(app.ButtonGroup_19);
            app.Button_58.Text = '3';
            app.Button_59 = uiradiobutton(app.ButtonGroup_19);
            app.Button_59.Text = '2';
            app.Button_60 = uiradiobutton(app.ButtonGroup_19);
            app.Button_60.Text = '1';
            app.ButtonGroup_20 = uibuttongroup(app.CTab);
            app.Button_61 = uiradiobutton(app.ButtonGroup_20);
            app.Button_61.Text = '5';
            app.Button_61.Value = true;
            app.Button_62 = uiradiobutton(app.ButtonGroup_20);
            app.Button_62.Text = '4';
            app.Button_63 = uiradiobutton(app.ButtonGroup_20);
            app.Button_63.Text = '3';
            app.Button_64 = uiradiobutton(app.ButtonGroup_20);
            app.Button_64.Text = '2';
            app.Button_65 = uiradiobutton(app.ButtonGroup_20);
            app.Button_65.Text = '1';
            app.ButtonGroup_21 = uibuttongroup(app.CTab);
            app.Button_66 = uiradiobutton(app.ButtonGroup_21);
            app.Button_66.Text = '5';
            app.Button_66.Value = true;
            app.Button_67 = uiradiobutton(app.ButtonGroup_21);
            app.Button_67.Text = '4';
            app.Button_68 = uiradiobutton(app.ButtonGroup_21);
            app.Button_68.Text = '3';
            app.Button_69 = uiradiobutton(app.ButtonGroup_21);
            app.Button_69.Text = '2';
            app.Button_70 = uiradiobutton(app.ButtonGroup_21);
            app.Button_70.Text = '1';
            app.ButtonGroup_22 = uibuttongroup(app.CTab);
            app.Button_71 = uiradiobutton(app.ButtonGroup_22);
            app.Button_71.Text = '5';
            app.Button_71.Value = true;
            app.Button_72 = uiradiobutton(app.ButtonGroup_22);
            app.Button_72.Text = '4';
            app.Button_73 = uiradiobutton(app.ButtonGroup_22);
            app.Button_73.Text = '3';
            app.Button_74 = uiradiobutton(app.ButtonGroup_22);
            app.Button_74.Text = '2';
            app.Button_75 = uiradiobutton(app.ButtonGroup_22);
            app.Button_75.Text = '1';
            app.C1FastingbeforetheexaminationmademeveryuncomfortableLabel = uilabel(app.CTab);
            app.C1FastingbeforetheexaminationmademeveryuncomfortableLabel.Text = {'C1: Fasting before the examination made'; ' me very uncomfortable'};
            app.C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_2 = uilabel(app.CTab);
            app.C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_2.Text = {'C2: Worried about insufficient bowel '; 'preparation affecting the examination'};
            app.C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_3 = uilabel(app.CTab);
            app.C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_3.Text = {'C5: The bowel preparation had '; 'anunpleasant taste'};
            app.C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_4 = uilabel(app.CTab);
            app.C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_4.Text = {'C7: The volume (amount) of the '; 'bowelpreparation was more than l '; 'hadexpected'};
            app.C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_5 = uilabel(app.CTab);
            app.C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_5.Text = {'C6: The bowel preparation tasted better'; 'than l had expected'};
            app.C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_6 = uilabel(app.CTab);
            app.C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_6.Text = {'C8: The amount of bowel preparation l'; 'had to drink was manageable'};
            app.C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_7 = uilabel(app.CTab);
            app.C1FastingbeforetheexaminationmakesyoufeeluncomfortableLabel_7.Text = {'C9: I am well aware of the correct usage '; 'of intestinal preparation solutions and '; 'medications'};
            app.DTab = uitab(app.TabGroup);
            app.DTab.Title = 'D-等待检查阶段';
            app.ButtonGroup_23 = uibuttongroup(app.DTab);
            app.Button_76 = uiradiobutton(app.ButtonGroup_23);
            app.Button_76.Text = '5';
            app.Button_76.Value = true;
            app.Button_77 = uiradiobutton(app.ButtonGroup_23);
            app.Button_77.Text = '4';
            app.Button_78 = uiradiobutton(app.ButtonGroup_23);
            app.Button_78.Text = '3';
            app.Button_79 = uiradiobutton(app.ButtonGroup_23);
            app.Button_79.Text = '2';
            app.Button_80 = uiradiobutton(app.ButtonGroup_23);
            app.Button_80.Text = '1';
            app.StronglyagreeLabel_3 = uilabel(app.DTab);
            app.StronglyagreeLabel_3.Text = 'Strongly agree';
            app.AgreeLabel_3 = uilabel(app.DTab);
            app.AgreeLabel_3.Text = 'Agree';
            app.NeitheragreeordisagreeLabel_3 = uilabel(app.DTab);
            app.NeitheragreeordisagreeLabel_3.Text = 'Neither agree or disagree';
            app.DisagreeLabel_3 = uilabel(app.DTab);
            app.DisagreeLabel_3.Text = 'Disagree';
            app.StronglydisagreeLabel_3 = uilabel(app.DTab);
            app.StronglydisagreeLabel_3.Text = 'Strongly disagree';
            app.D4IhadnounansweredquestionsbeforethetestLabel = uilabel(app.DTab);
            app.D4IhadnounansweredquestionsbeforethetestLabel.Text = {'D4: I had no unanswered questions '; 'before the test'};
            app.ETab = uitab(app.TabGroup);
            app.ETab.Title = 'E-实施阶段';
            app.ButtonGroup_24 = uibuttongroup(app.ETab);
            app.Button_81 = uiradiobutton(app.ButtonGroup_24);
            app.Button_81.Text = '5';
            app.Button_81.Value = true;
            app.Button_82 = uiradiobutton(app.ButtonGroup_24);
            app.Button_82.Text = '4';
            app.Button_83 = uiradiobutton(app.ButtonGroup_24);
            app.Button_83.Text = '3';
            app.Button_84 = uiradiobutton(app.ButtonGroup_24);
            app.Button_84.Text = '2';
            app.Button_85 = uiradiobutton(app.ButtonGroup_24);
            app.Button_85.Text = '1';
            app.StronglyagreeLabel_4 = uilabel(app.ETab);
            app.StronglyagreeLabel_4.Text = 'Strongly agree';
            app.AgreeLabel_4 = uilabel(app.ETab);
            app.AgreeLabel_4.Text = 'Agree';
            app.NeitheragreeordisagreeLabel_4 = uilabel(app.ETab);
            app.NeitheragreeordisagreeLabel_4.Text = 'Neither agree or disagree';
            app.DisagreeLabel_4 = uilabel(app.ETab);
            app.DisagreeLabel_4.Text = 'Disagree';
            app.StronglydisagreeLabel_4 = uilabel(app.ETab);
            app.StronglydisagreeLabel_4.Text = 'Strongly disagree';
            app.ButtonGroup_25 = uibuttongroup(app.ETab);
            app.Button_86 = uiradiobutton(app.ButtonGroup_25);
            app.Button_86.Text = '5';
            app.Button_86.Value = true;
            app.Button_87 = uiradiobutton(app.ButtonGroup_25);
            app.Button_87.Text = '4';
            app.Button_88 = uiradiobutton(app.ButtonGroup_25);
            app.Button_88.Text = '3';
            app.Button_89 = uiradiobutton(app.ButtonGroup_25);
            app.Button_89.Text = '2';
            app.Button_90 = uiradiobutton(app.ButtonGroup_25);
            app.Button_90.Text = '1';
            app.ButtonGroup_26 = uibuttongroup(app.ETab);
            app.Button_91 = uiradiobutton(app.ButtonGroup_26);
            app.Button_91.Text = '5';
            app.Button_91.Value = true;
            app.Button_92 = uiradiobutton(app.ButtonGroup_26);
            app.Button_92.Text = '4';
            app.Button_93 = uiradiobutton(app.ButtonGroup_26);
            app.Button_93.Text = '3';
            app.Button_94 = uiradiobutton(app.ButtonGroup_26);
            app.Button_94.Text = '2';
            app.Button_95 = uiradiobutton(app.ButtonGroup_26);
            app.Button_95.Text = '1';
            app.ButtonGroup_27 = uibuttongroup(app.ETab);
            app.Button_96 = uiradiobutton(app.ButtonGroup_27);
            app.Button_96.Text = '5';
            app.Button_96.Value = true;
            app.Button_97 = uiradiobutton(app.ButtonGroup_27);
            app.Button_97.Text = '4';
            app.Button_98 = uiradiobutton(app.ButtonGroup_27);
            app.Button_98.Text = '3';
            app.Button_99 = uiradiobutton(app.ButtonGroup_27);
            app.Button_99.Text = '2';
            app.Button_100 = uiradiobutton(app.ButtonGroup_27);
            app.Button_100.Text = '1';
            app.E9IwassatisfiedwiththeexplanationgiventomeaboutthetestLabel = uilabel(app.ETab);
            app.E9IwassatisfiedwiththeexplanationgiventomeaboutthetestLabel.Text = {'E9: I was satisfied with the explanation '; ' given to me about the test'};
            app.E13ThetesttooklongerthanIexpectedLabel = uilabel(app.ETab);
            app.E13ThetesttooklongerthanIexpectedLabel.Text = 'E13: The test took longer than I expected';
            app.E22IfeltembarrassedbythediscomfortIexperiencedLabel = uilabel(app.ETab);
            app.E22IfeltembarrassedbythediscomfortIexperiencedLabel.Text = {'E22: I felt embarrassed by the discomfort '; 'I experienced'};
            app.E23IfeltembarrassedbythepainIexperiencedLabel = uilabel(app.ETab);
            app.E23IfeltembarrassedbythepainIexperiencedLabel.Text = {'E23: I felt embarrassed by the pain I '; ' experienced'};
            app.FTab = uitab(app.TabGroup);
            app.FTab.Title = 'F-检查后';
            app.ButtonGroup_29 = uibuttongroup(app.FTab);
            app.Button_106 = uiradiobutton(app.ButtonGroup_29);
            app.Button_106.Text = '5';
            app.Button_106.Value = true;
            app.Button_107 = uiradiobutton(app.ButtonGroup_29);
            app.Button_107.Text = '4';
            app.Button_108 = uiradiobutton(app.ButtonGroup_29);
            app.Button_108.Text = '3';
            app.Button_109 = uiradiobutton(app.ButtonGroup_29);
            app.Button_109.Text = '2';
            app.Button_110 = uiradiobutton(app.ButtonGroup_29);
            app.Button_110.Text = '1';
            app.StronglyagreeLabel_5 = uilabel(app.FTab);
            app.StronglyagreeLabel_5.Text = 'Strongly agree';
            app.AgreeLabel_5 = uilabel(app.FTab);
            app.AgreeLabel_5.Text = 'Agree';
            app.NeitheragreeordisagreeLabel_5 = uilabel(app.FTab);
            app.NeitheragreeordisagreeLabel_5.Text = 'Neither agree or disagree';
            app.DisagreeLabel_5 = uilabel(app.FTab);
            app.DisagreeLabel_5.Text = 'Disagree';
            app.StronglydisagreeLabel_5 = uilabel(app.FTab);
            app.StronglydisagreeLabel_5.Text = 'Strongly disagree';
            app.ButtonGroup_30 = uibuttongroup(app.FTab);
            app.Button_111 = uiradiobutton(app.ButtonGroup_30);
            app.Button_111.Text = '5';
            app.Button_111.Value = true;
            app.Button_112 = uiradiobutton(app.ButtonGroup_30);
            app.Button_112.Text = '4';
            app.Button_113 = uiradiobutton(app.ButtonGroup_30);
            app.Button_113.Text = '3';
            app.Button_114 = uiradiobutton(app.ButtonGroup_30);
            app.Button_114.Text = '2';
            app.Button_115 = uiradiobutton(app.ButtonGroup_30);
            app.Button_115.Text = '1';
            app.ButtonGroup_31 = uibuttongroup(app.FTab);
            app.Button_116 = uiradiobutton(app.ButtonGroup_31);
            app.Button_116.Text = '5';
            app.Button_116.Value = true;
            app.Button_117 = uiradiobutton(app.ButtonGroup_31);
            app.Button_117.Text = '4';
            app.Button_118 = uiradiobutton(app.ButtonGroup_31);
            app.Button_118.Text = '3';
            app.Button_119 = uiradiobutton(app.ButtonGroup_31);
            app.Button_119.Text = '2';
            app.Button_120 = uiradiobutton(app.ButtonGroup_31);
            app.Button_120.Text = '1';
            app.ButtonGroup_32 = uibuttongroup(app.FTab);
            app.Button_121 = uiradiobutton(app.ButtonGroup_32);
            app.Button_121.Text = '5';
            app.Button_121.Value = true;
            app.Button_122 = uiradiobutton(app.ButtonGroup_32);
            app.Button_122.Text = '4';
            app.Button_123 = uiradiobutton(app.ButtonGroup_32);
            app.Button_123.Text = '3';
            app.Button_124 = uiradiobutton(app.ButtonGroup_32);
            app.Button_124.Text = '2';
            app.Button_125 = uiradiobutton(app.ButtonGroup_32);
            app.Button_125.Text = '1';
            app.F2IhaddiscomfortafterthetestLabel = uilabel(app.FTab);
            app.F2IhaddiscomfortafterthetestLabel.Text = 'F2: I had discomfort after the test';
            app.F6IhaddiscomfortafterthetestLabel = uilabel(app.FTab);
            app.F6IhaddiscomfortafterthetestLabel.Text = {'F6: When I left the hospital, I was clear '; ' about what the next steps would be'};
            app.F6IhaddiscomfortafterthetestLabel_2 = uilabel(app.FTab);
            app.F6IhaddiscomfortafterthetestLabel_2.Text = {'F9: The staff reminded me of the issues '; 'to pay attention to after the inspection'};
            app.F6IhaddiscomfortafterthetestLabel_3 = uilabel(app.FTab);
            app.F6IhaddiscomfortafterthetestLabel_3.Text = {'F10: The staff explained the inspection '; 'results to me and provided '; 'some suggestions'};
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '结果输出';
            app.UIAxes = uiaxes(app.Tab);
            xlabel(app.UIAxes, 'Dimension number')
            ylabel(app.UIAxes, 'Score')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.TextArea = uitextarea(app.Tab);
            app.ScoreforeachdimensionLabel = uilabel(app.Tab);
            app.ScoreforeachdimensionLabel.Text = 'Score for each dimension';
            app.PREMresultevaluationLabel = uilabel(app.Tab);
            app.PREMresultevaluationLabel.Text = 'PREM result evaluation';
            app.StartOutputButton = uibutton(app.Tab, 'push');
            app.StartOutputButton.ButtonPushedFcn = createCallbackFcn(app, @StartOutputButtonPushed, true);
            app.StartOutputButton.Icon = fullfile(pathToMLAPP, '结果输出.png');
            app.StartOutputButton.Text = 'Start Output';
            app.CleanupButton = uibutton(app.Tab, 'push');
            app.CleanupButton.ButtonPushedFcn = createCallbackFcn(app, @CleanupButtonPushed, true);
            app.CleanupButton.Icon = fullfile(pathToMLAPP, '清理.png');
            app.CleanupButton.Text = 'Clean up';
            app.SaveResultsButton = uibutton(app.Tab, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Icon = fullfile(pathToMLAPP, '保存.png');
            app.SaveResultsButton.Text = 'Save Results';
            app.GetHelpButton = uibutton(app.Tab, 'push');
            app.GetHelpButton.ButtonPushedFcn = createCallbackFcn(app, @GetHelpButtonPushed, true);
            app.GetHelpButton.Icon = fullfile(pathToMLAPP, '帮助.png');
            app.GetHelpButton.Text = 'Get Help';
            app.Label_2 = uilabel(app.PREMUIFigure);
            app.Label_2.Text = {'Gastrointestinal endoscopy patient report '; 'experience measurement system'};
            app.ABasicInformationButton_2 = uibutton(app.PREMUIFigure, 'push');
            app.ABasicInformationButton_2.ButtonPushedFcn = createCallbackFcn(app, @ABasicInformationButton_2Pushed, true);
            app.ABasicInformationButton_2.Icon = fullfile(pathToMLAPP, '基本信息.png');
            app.ABasicInformationButton_2.Text = 'A-Basic Information';
            app.BBeforeHospitalButton_2 = uibutton(app.PREMUIFigure, 'push');
            app.BBeforeHospitalButton_2.ButtonPushedFcn = createCallbackFcn(app, @BBeforeHospitalButton_2Pushed, true);
            app.BBeforeHospitalButton_2.Icon = fullfile(pathToMLAPP, 'B.png');
            app.BBeforeHospitalButton_2.Text = 'B-Before Hospital';
            app.CBowelPreparationButton_2 = uibutton(app.PREMUIFigure, 'push');
            app.CBowelPreparationButton_2.ButtonPushedFcn = createCallbackFcn(app, @CBowelPreparationButton_2Pushed, true);
            app.CBowelPreparationButton_2.Icon = fullfile(pathToMLAPP, 'C.png');
            app.CBowelPreparationButton_2.Text = 'C-Bowel Preparation';
            app.DPendingInspectionButton_2 = uibutton(app.PREMUIFigure, 'push');
            app.DPendingInspectionButton_2.ButtonPushedFcn = createCallbackFcn(app, @DPendingInspectionButton_2Pushed, true);
            app.DPendingInspectionButton_2.Icon = fullfile(pathToMLAPP, 'D.png');
            app.DPendingInspectionButton_2.Text = 'D-Pending Inspection';
            app.EInspectionStageButton_2 = uibutton(app.PREMUIFigure, 'push');
            app.EInspectionStageButton_2.ButtonPushedFcn = createCallbackFcn(app, @EInspectionStageButton_2Pushed, true);
            app.EInspectionStageButton_2.Icon = fullfile(pathToMLAPP, 'E.jpg');
            app.EInspectionStageButton_2.Text = 'E-Inspection Stage';
            app.FAfterInspectionButton_2 = uibutton(app.PREMUIFigure, 'push');
            app.FAfterInspectionButton_2.ButtonPushedFcn = createCallbackFcn(app, @FAfterInspectionButton_2Pushed, true);
            app.FAfterInspectionButton_2.Icon = fullfile(pathToMLAPP, 'F.png');
            app.FAfterInspectionButton_2.Text = 'F-After Inspection';
            app.OutputResultButton_2 = uibutton(app.PREMUIFigure, 'push');
            app.OutputResultButton_2.ButtonPushedFcn = createCallbackFcn(app, @OutputResultButton_2Pushed, true);
            app.OutputResultButton_2.Icon = fullfile(pathToMLAPP, '输出.png');
            app.OutputResultButton_2.Text = 'Output Result';
            app.Label_3 = uilabel(app.PREMUIFigure);
            app.Label_3.Text = '';
            % Show the figure after all components are created
            app.PREMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PREMUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PREMUIFigure)
        end
    end
end